classdef video2calcUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ConvertButton                matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        FPSKnob                      matlab.ui.control.DiscreteKnob
        FPSKnobLabel                 matlab.ui.control.Label
        ChunkSizeDropDown            matlab.ui.control.DropDown
        ChunkSizeDropDownLabel       matlab.ui.control.Label
        OutputNameEditField          matlab.ui.control.EditField
        OutputNameLabel              matlab.ui.control.Label
        FilePathEditField            matlab.ui.control.EditField
        InputFilePathEditFieldLabel  matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        Filepath % String
        OutputName % String
        ChunkSize % int
        Fps % int
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
        [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files'});
        % Check if the user clicked "Cancel" (returns 0)
        if ischar(file)
            % Combine them into a single string
            app.Filepath = fullfile(path, file);
            
            % Update your UI component
            app.FilePathEditField.Value = app.Filepath;
        else
            % Optional: Handle the cancel case
            uialert(app.UIFigure, 'No file was selected.', 'Selection Canceled');
        end
            
        end

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
            app.Filepath = app.FilePathEditField.Value;
            
        end

        % Value changed function: OutputNameEditField
        function OutputNameEditFieldValueChanged(app, event)
            app.OutputName = app.OutputNameEditField.Value;
            
        end

        % Value changed function: ChunkSizeDropDown
        function ChunkSizeDropDownValueChanged(app, event)
            app.ChunkSize = string(app.ChunkSizeDropDown.Value);
            
        end

        % Value changed function: FPSKnob
        function FPSKnobValueChanged(app, event)
            app.Fps = string(app.FPSKnob.Value);
            app.EditField.Value = app.Fps;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Fps = string(app.EditField.Value);
            
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            video2calc(app.Filepath, "-o", app.OutputName, "-c", app.ChunkSize, "-fps", app.Fps);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.InputFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.InputFilePathEditFieldLabel.Text = 'Input File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.OutputNameLabel = uilabel(app.UIFigure);
            app.OutputNameLabel.Text = 'Output Name ';
            app.OutputNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputNameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputNameEditFieldValueChanged, true);
            app.ChunkSizeDropDownLabel = uilabel(app.UIFigure);
            app.ChunkSizeDropDownLabel.Text = 'Chunk Size';
            app.ChunkSizeDropDown = uidropdown(app.UIFigure);
            app.ChunkSizeDropDown.Items = {'4000', '8000', '16000', '32000', '64000'};
            app.ChunkSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChunkSizeDropDownValueChanged, true);
            app.ChunkSizeDropDown.Value = '8000';
            app.FPSKnobLabel = uilabel(app.UIFigure);
            app.FPSKnobLabel.Text = 'FPS';
            app.FPSKnob = uiknob(app.UIFigure, 'discrete');
            app.FPSKnob.Items = {'10', '15', '20', '30', '40', '60'};
            app.FPSKnob.ValueChangedFcn = createCallbackFcn(app, @FPSKnobValueChanged, true);
            app.FPSKnob.Value = '10';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video2calcUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end